classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        removepersonButton        matlab.ui.control.Button
        plotpersonButton          matlab.ui.control.Button
        LinearDobotMagicianLabel  matlab.ui.control.Label
        ABBIRB1200Label           matlab.ui.control.Label
        ZEditField_2              matlab.ui.control.NumericEditField
        ZEditField_2Label         matlab.ui.control.Label
        YEditField_2              matlab.ui.control.NumericEditField
        YEditField_2Label         matlab.ui.control.Label
        XEditField_2              matlab.ui.control.NumericEditField
        XEditField_2Label         matlab.ui.control.Label
        MOVEButton_2              matlab.ui.control.Button
        Joint6Slider_2            matlab.ui.control.Slider
        Joint6Slider_2Label       matlab.ui.control.Label
        Joint5Slider_2            matlab.ui.control.Slider
        Joint5Slider_2Label       matlab.ui.control.Label
        Joint4Slider_2            matlab.ui.control.Slider
        Joint4Slider_2Label       matlab.ui.control.Label
        Joint3Slider_2            matlab.ui.control.Slider
        Joint3Slider_2Label       matlab.ui.control.Label
        Joint2Slider_2            matlab.ui.control.Slider
        Joint2Slider_2Label       matlab.ui.control.Label
        Joint1Slider_2            matlab.ui.control.Slider
        Joint1Slider_2Label       matlab.ui.control.Label
        Seat3Button               matlab.ui.control.Button
        Seat2Button               matlab.ui.control.Button
        Seat1Button               matlab.ui.control.Button
        MOVEButton                matlab.ui.control.Button
        ZEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        ResumeButton              matlab.ui.control.Button
        GripperSwitch             matlab.ui.control.Switch
        GripperSwitchLabel        matlab.ui.control.Label
        RESETROBOTButton          matlab.ui.control.Button
        ESTOPButton               matlab.ui.control.Button
        VodkaOrangeJuiceButton    matlab.ui.control.Button
        WhiskeyCokeButton         matlab.ui.control.Button
        Joint6Slider              matlab.ui.control.Slider
        Joint6SliderLabel         matlab.ui.control.Label
        Joint5Slider              matlab.ui.control.Slider
        Joint5SliderLabel         matlab.ui.control.Label
        Joint4Slider              matlab.ui.control.Slider
        Joint4SliderLabel         matlab.ui.control.Label
        Joint3Slider              matlab.ui.control.Slider
        Joint3SliderLabel         matlab.ui.control.Label
        Joint2Slider              matlab.ui.control.Slider
        Joint2SliderLabel         matlab.ui.control.Label
        Joint1Slider              matlab.ui.control.Slider
        Joint1SliderLabel         matlab.ui.control.Label
        VodkaLemonadeButton       matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentJoint;
        TargetJoint;
        steps = 20;
        IRB1200;% Description
        DOBOT;
        gin;
        vodka;
        whiskey;
        lemonade;
        coke;
        table;
        table_vertices;
        orangeJuice;
        gin_vertices;
        vodka_vertices;
        whiskey_vertices;
        lemonade_vertices;
        coke_vertices;
        orangeJuice_vertices;
        gripperL;
        gripperR;
        Open;
        Close;
        qMatrixOpen;
        qMatrixClose;
        mixingpos;
        RedSoloCup;
        RedSoloCup_vertices;
        movecup;
        position1;
        position2;
        q0;
        position;
        pourDrink;
        b;
        numSteps;
        qMatrix;
        Value;
        EStopStatus;
        Xpos;
        Xpos2;
        Ypos;
        Ypos2;
        Zpos;
        Zpos2;
        lightcurtain;
        lightcurtain_vertices;
        s;
        person;
        light;
        plotPos;


    end
    
    methods (Access = private)
        
        function makeVodkaLemonade(app,IRB1200, gripperR, gripperL, numSteps,mixingpos,qMatrixClose,qMatrixOpen,vodka_vertices,gin_vertices,whiskey_vertices,coke_vertices,lemonade_vertices,orangeJuice_vertices,vodka,gin,whiskey,coke,lemonade,orangeJuice,DOBOT)
           disp(['Making Vodka Lemonade...'])

            app.seatmovecup(app.s);

            app.position = [-0.4 0.4 0.8];
            app.pourDrink = 0;
            app.b = 0;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = [-0.4 0.4 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.closeGripper(app.gripperR,app.gripperL,app.qMatrixClose);
            app.b = 1;
            
            app.position = [-0.4 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
          
        
            app.position = app.mixingpos;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 1;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 2;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.pourDrink = 0;
            app.position = [-0.4 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.position = [-0.4 0.4 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.b = 0;
            app.openGripper(app.gripperR,app.gripperL,app.qMatrixOpen);
        
            app.position = [-0.4 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
        %%%%%Lemonade
            app.position = [-0.4 0.7 1];
            app.pourDrink = 0;
            app.b = 0;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = [-0.4 0.7 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.closeGripper(app.gripperR,app.gripperL,app.qMatrixClose);
            app.b = 5;
        
            app.position = [-0.4 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
            app.position = app.mixingpos;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 1;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 2;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.pourDrink = 0;
            app.position = [-0.4 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
            app.position = [-0.4 0.7 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.b = 0;
            app.openGripper(app.gripperR,app.gripperL,app.qMatrixOpen);
        
            app.position = [-0.4 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
            app.seatmovecup(app.s);
            
            disp(['DONE.']);  
        end
        function makeWhiskeyCoke(app,IRB1200, gripperR, gripperL, numSteps,mixingpos,qMatrixClose,qMatrixOpen,vodka_vertices,gin_vertices,whiskey_vertices,coke_vertices,lemonade_vertices,orangeJuice_vertices,vodka,gin,whiskey,coke,lemonade,orangeJuice,DOBOT)
           disp(['Making Whiskey Coke...'])

            app.seatmovecup(app.s);

            app.position = [-0.5 0.4 0.8];
            app.pourDrink = 0;
            app.b = 0;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = [-0.5 0.4 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.closeGripper(app.gripperR,app.gripperL,app.qMatrixClose);
            app.b = 3;
            
            app.position = [-0.5 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = app.mixingpos;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 1;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 2;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.pourDrink = 0;
            app.position = [-0.5 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.position = [-0.5 0.4 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.b = 0;
            app.openGripper(app.gripperR,app.gripperL,app.qMatrixOpen);
        
            app.position = [-0.5 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
        %%%%%Coke
            app.position = [-0.3 0.7 1];
            app.pourDrink = 0;
            app.b = 0;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = [-0.3 0.7 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.closeGripper(app.gripperR,app.gripperL,app.qMatrixClose);
            app.b = 4;
        
            app.position = [-0.3 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
            app.position = app.mixingpos;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 1;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 2;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.pourDrink = 0;
            app.position = [-0.3 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
            app.position = [-0.3 0.7 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.b = 0;
            app.openGripper(app.gripperR,app.gripperL,app.qMatrixOpen);
        
            app.position = [-0.3 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 

            app.seatmovecup(app.s);
            
            disp(['DONE.']); 

        end
            
        function makeVodkaOJ(app,IRB1200, gripperR, gripperL, numSteps,mixingpos,qMatrixClose,qMatrixOpen,vodka_vertices,gin_vertices,whiskey_vertices,coke_vertices,lemonade_vertices,orangeJuice_vertices,vodka,gin,whiskey,coke,lemonade,orangeJuice,DOBOT)
            disp(['Making Vodka OrangeJuice...'])
        
            app.seatmovecup(app.s);

            app.position = [-0.4 0.4 0.8];
            app.pourDrink = 0;
            app.b = 0;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = [-0.4 0.4 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.closeGripper(app.gripperR,app.gripperL,app.qMatrixClose);
            app.b = 1;
            
            app.position = [-0.4 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = app.mixingpos;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 1;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 2;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.pourDrink = 0;
            app.position = [-0.4 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.position = [-0.4 0.4 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.b = 0;
            app.openGripper(app.gripperR,app.gripperL,app.qMatrixOpen);
        
            app.position = [-0.4 0.4 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
        %%%%%OrangeJuice
            app.position = [-0.5 0.7 0.8];
            app.pourDrink = 0;
            app.b = 0;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.position = [-0.5 0.7 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.closeGripper(app.gripperR,app.gripperL,app.qMatrixClose);
            app.b = 6;
        
            app.position = [-0.5 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
            app.position = app.mixingpos;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 1;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
        
            app.pourDrink = 2;
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
           
            app.pourDrink = 0;
            app.position = [-0.5 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            
            app.position = [-0.5 0.7 0.7];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 
            app.b = 0;
            app.openGripper(app.gripperR,app.gripperL,app.qMatrixOpen);
        
            app.position = [-0.5 0.7 1];
            app.moveIRB1200(app.IRB1200, app.numSteps, app.gripperR, app.gripperL, app.position,app.pourDrink,app.b,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice); 

            app.seatmovecup(app.s);
            
            disp(['DONE.']); 

        end
        
        function seatmovecup(app,s)
            switch s
                case 1
                        app.RedSoloCup=PlaceObject('RedSoloCup.ply', [0 0 0]);
                        app.RedSoloCup_vertices=get(app.RedSoloCup,'Vertices');
                        RedSoloCuptr=transl(0.7,-0.5,0.5);
                        transformedVertices=[app.RedSoloCup_vertices,ones(size(app.RedSoloCup_vertices,1),1)]*RedSoloCuptr';
                        set(app.RedSoloCup,'Vertices',transformedVertices(:,1:3));

                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.65 -0.5 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.8];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                         
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.25 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.7];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        app.s = 4;
                case 2
                        
                        app.RedSoloCup=PlaceObject('RedSoloCup.ply', [0 0 0]);
                        app.RedSoloCup_vertices=get(app.RedSoloCup,'Vertices');
                        RedSoloCuptr=transl(0.7,0,0.5);
                        transformedVertices=[app.RedSoloCup_vertices,ones(size(app.RedSoloCup_vertices,1),1)]*RedSoloCuptr';
                        set(app.RedSoloCup,'Vertices',transformedVertices(:,1:3));

                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.65 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.8];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                         
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.25 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.7];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        app.s = 5;

                case 3
                        app.RedSoloCup=PlaceObject('RedSoloCup.ply', [0 0 0]);
                        app.RedSoloCup_vertices=get(app.RedSoloCup,'Vertices');
                        RedSoloCuptr=transl(0.7,0.5,0.5);
                        transformedVertices=[app.RedSoloCup_vertices,ones(size(app.RedSoloCup_vertices,1),1)]*RedSoloCuptr';
                        set(app.RedSoloCup,'Vertices',transformedVertices(:,1:3));

                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.65 0.5 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.8];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                         
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.25 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.7];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        app.s = 6;
                    
                case 4
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.25 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.8];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                         
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.65 -0.5 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.7];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        
                case 5
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.25 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.8];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                         
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.65 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.7];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        
                case 6
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.25 0 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                       
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.8];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                         
                        app.movecup = 1;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.65 0.5 0.65];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        
                        app.movecup = 0;
                        app.position1 = (app.DOBOT.model.fkine(app.DOBOT.model.getpos()).t)';
                        app.position2 = [0.5 0 0.7];
                        app.q0 = app.DOBOT.model.getpos();
                        [app.qMatrix,app.steps]=app.RMRCmoveDobot(app.DOBOT,app.numSteps,app.position1,app.position2,app.RedSoloCup_vertices,app.RedSoloCup,app.movecup,app.q0);
                        
                       
            end
        end


        function [qMatrix,steps]=RMRCmoveDobot(app,DOBOT,numSteps,position1,position2,RedSoloCup_vertices,RedSoloCup,movecup,q0)
            t = 1;                          % Total time (s)
            deltaT = 0.05;                % Control frequency
            steps = t/deltaT;                 % No. of steps for simulation
            % delta = 2*pi/steps;             % Small angle change
            epsilon = 0.05;                   % Threshold value for manipulability/Damped Least Squares
            W = diag([1 1 1 0.1 0.1 0.1]);    % Weighting matrix for the velocity vector
 
            % % 1.2) Allocate array data
            m = zeros(steps,1);             % Array for Measure of Manipulability
            qMatrix = zeros(steps,6);       % Array for joint angles
            qdot = zeros(steps,6);          % Array for joint velocities
            theta = zeros(3,steps);         % Array for roll-pitch-yaw angles
            x = zeros(3,steps);             % Array for x-y-z trajectory
            % positionError = zeros(3,steps); % For plotting trajectory error
            % angleError = zeros(3,steps);    % For plotting trajectory error
 
            % 1.3) Set up trajectory, initial pose
            x1 = position1;
            x2 = position2;
            
            % 3.7
            s = lspb(0,1,steps); % Create interpolation scalar
            for i = 1:steps
                x(:,i) = x1*(1-s(i)) + s(i)*x2; % Create trajectory in x-y plane
                theta(1,i) = 0;
                theta(2,i) = 0;
                theta(3,i) = 0;
            end
 
            T = [rpy2r(theta(1,1),theta(2,1),theta(3,1)) x(:,1);zeros(1,3) 1];          % Create transformation of first point and angle
            % q0 = zeros(1,6);                                                          % Initial guess for joint angles
            qMatrix(1,:) = DOBOT.model.ikcon(T,q0);                                     % Solve joint angles to achieve first waypoint                        
 
            % 1.4) Track the trajectory with RMRC
            for i = 1:steps-1
                % UPDATE: fkine function now returns an SE3 object. To obtain the
                % Transform Matrix, access the variable in the object 'T' with '.T'.
                T = DOBOT.model.fkine(qMatrix(i,:)).T;                                  % Get forward transformation at current joint state
                deltaX = x(:,i+1) - T(1:3,4);                                         	% Get position error from next waypoint
                Rd = rpy2r(theta(1,i+1),theta(2,i+1),theta(3,i+1));                     % Get next RPY angles, convert to rotation matrix
                Ra = T(1:3,1:3);                                                        % Current end-effector rotation matrix
                Rdot = (1/deltaT)*(Rd - Ra);                                            % Calculate rotation matrix error
                S = Rdot*Ra';                                                           % Skew symmetric!
                linear_velocity = (1/deltaT)*deltaX;
                angular_velocity = [S(3,2);S(1,3);S(2,1)];                              % Check the structure of Skew Symmetric matrix!!
                % deltaTheta = tr2rpy(Rd*Ra');                                          % Convert rotation matrix to RPY angles
                xdot = W*[linear_velocity;angular_velocity];                          	% Calculate end-effector velocity to reach next waypoint.
                J = DOBOT.model.jacob0(qMatrix(i,:));                                   % Get Jacobian at current joint state
                m(i) = sqrt(det(J*J'));
                if m(i) < epsilon  % If manipulability is less than given threshold
                    lambda = (1 - m(i)/epsilon)*5E-2;
                else
                    lambda = 0;
                end
                invJ = inv(J'*J + lambda *eye(6))*J';                                   % DLS Inverse
                qdot(i,:) = (invJ*xdot)';                                               % Solve the RMRC equation (you may need to transpose the         vector)
                for j = 1:6                                                             % Loop through joints 1 to 6
                    if qMatrix(i,j) + deltaT*qdot(i,j) < DOBOT.model.qlim(j,1)          % If next joint angle is lower than joint limit...
                        qdot(i,j) = 0; % Stop the motor
                    elseif qMatrix(i,j) + deltaT*qdot(i,j) > DOBOT.model.qlim(j,2)      % If next joint angle is greater than joint limit ...
                        qdot(i,j) = 0; % Stop the motor
                    end
                end
                qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qdot(i,:);                      	% Update next joint state based on joint velocities
                % positionError(:,i) = x(:,i+1) - T(1:3,4);                             % For plotting
                % angleError(:,i) = deltaTheta;                                         % For plotting
            end
            for i = 1:steps   
                    DOBOT.model.animate(qMatrix(i,:));
                    collision = app.checkCollisions();
                    if collision == 1
                            return
                    end
                    if app.EStopStatus == true
                        return
                    end
                    
                    if movecup == 1
                        tr=DOBOT.model.fkine(DOBOT.model.getpos()).T * transl(0.05,0,-0.15);
                        transformedVertices=[RedSoloCup_vertices,ones(size(RedSoloCup_vertices,1),1)]*(double(tr))';
                        set(RedSoloCup,'Vertices',transformedVertices(:,1:3));
                    end
                    drawnow;
            end
             %plot3(x(1,:),x(2,:),x(3,:),'k.','LineWidth',1)                            % Uncomment this to check the trajectory
        end

        function moveIRB1200(app,IRB1200, numSteps, gripperR, gripperL, position,pourDrink,b,vodka_vertices,gin_vertices,whiskey_vertices,coke_vertices,lemonade_vertices,orangeJuice_vertices,vodka,gin,whiskey,coke,lemonade,orangeJuice)
             
                q1 = IRB1200.model.ikcon(transl(position)*trotx(270,"deg"),[1 1 1 0 0 0])
                
                if pourDrink == 1
                    q1 = IRB1200.model.getpos() + [0 0 0 0 0 deg2rad(135)];
                end
                if pourDrink == 2
                    q1 = IRB1200.model.getpos() + [0 0 0 0 0 deg2rad(-135)];
                end 
                %move over the bricks position 
                app.qMatrix = jtraj(IRB1200.model.getpos(),q1,numSteps);
                for i=1:numSteps
                    %animate robot model to go to over the brick for pickup
                    if app.EStopStatus == true
                        return
                    end
                    
                    IRB1200.model.animate(app.qMatrix(i,:)); 
                    %changes the location of the  R and L gripper to the updated end effector pose
                    gripperR.model.base = IRB1200.model.fkine(IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                    gripperL.model.base = IRB1200.model.fkine(IRB1200.model.getpos).T * trotx(pi/2);
                    %animate the chaßßnge of position for gripper
                    gripperR.model.animate(gripperR.model.getpos);
                    gripperL.model.animate(gripperL.model.getpos);
                    if b ~= 0
                        app.movebottle(IRB1200,vodka_vertices,gin_vertices,whiskey_vertices,coke_vertices,lemonade_vertices,orangeJuice_vertices,vodka,gin,whiskey,coke,lemonade,orangeJuice,b);
                    end
                    drawnow();
                    collision = app.checkCollisions();
                    if collision == 1
                        return
                    end


                end
        end
        function movebottle(app,IRB1200,vodka_vertices,gin_vertices,whiskey_vertices,coke_vertices,lemonade_vertices,orangeJuice_vertices,vodka,gin,whiskey,coke,lemonade,orangeJuice,b)
            tr=IRB1200.model.fkine(IRB1200.model.getpos()).T * trotx(90,'deg') * transl(0,0.1,-0.2);
            switch b
                case 0
                        %no bottle being held
                case 1  
                        transformedVertices=[vodka_vertices,ones(size(vodka_vertices,1),1)]*(double(tr))';
                        set(vodka,'Vertices',transformedVertices(:,1:3));
                case 2                
                        transformedVertices=[gin_vertices,ones(size(gin_vertices,1),1)]*(double(tr))';
                        set(gin,'Vertices',transformedVertices(:,1:3));
                case 3             
                        transformedVertices=[whiskey_vertices,ones(size(whiskey_vertices,1),1)]*(double(tr))';
                        set(whiskey,'Vertices',transformedVertices(:,1:3));
                case 4                
                        transformedVertices=[coke_vertices,ones(size(coke_vertices,1),1)]*(double(tr))';
                        set(coke,'Vertices',transformedVertices(:,1:3));
                case 5                
                        transformedVertices=[lemonade_vertices,ones(size(lemonade_vertices,1),1)]*(double(tr))';
                        set(lemonade,'Vertices',transformedVertices(:,1:3));
                case 6
                        transformedVertices=[orangeJuice_vertices,ones(size(orangeJuice_vertices,1),1)]*(double(tr))';
                        set(orangeJuice,'Vertices',transformedVertices(:,1:3));
        
            end
        end
        function closeGripper(app,gripperR,gripperL,qMatrixClose)
            pause(0.5)
            %close gripper R and L
            disp('Closing Gripper...');
            app.gripperR.model.animate(app.qMatrixClose);
            app.gripperL.model.animate(app.qMatrixClose);
            pause(0.5)
        end

        function openGripper(app,gripperR,gripperL,qMatrixOpen)
            pause(0.5)
            %open gripper R and L
            disp('Open Gripper...');
            app.gripperR.model.animate(app.qMatrixOpen);
            app.gripperL.model.animate(app.qMatrixOpen);
            pause(0.5)
        end
        

        function Transform = fkineIRBlinks(app,q,return_link)

            if return_link > 7  % 6 degrees of freedom and a tool transform
                disp("The transform for the link number cannot be returned as it exceeds the UR5 robot's links");
                return
            end
            linksArray = {app.IRB1200.model.base, ...
                        trotz(q(1)) * transl(0,0,0.3991) * trotx(-pi/2), ...
                        trotz(q(2)-pi/2) * transl(0.45,0,0)  * trotx(0), ...
                        trotz(q(3)) * transl(0.042,0,0) * trotx(-pi/2), ...
                        trotz(q(4)) * transl(0,0,0.451) * trotx(pi/2), ...
                        trotz(q(5)) * transl(0,0,0) * trotx(-pi/2), ...
                        trotz(q(6)) * transl(0,0,0.082)  * trotx(0), ...
                        eye(4)};
            % linksArray = {base_tr, joint1To2, joint2To3, joint3To4, joint4To5, joint5To6, joint6To7, tool_tr};
            
        
            Transform = linksArray{1}.T;
            for i = 2:return_link+1
                Transform = Transform * linksArray{i};
            end
        
            % End effector Transform = base_tr*joint0To1*joint1To2*joint2To3*joint3To4*joint4To5*joint5To6*joint6To7*tool_tr; 
        end
        
        function collision = checkCollisions(app)
    
            IRB1200_link_sizes_for_collisions = {[0.15,0.15,0.3991/2],...
                [0.450/2,0.15,0.15],...
                [0.042/2,0.15,0.15],...
                [0.1,0.1,0.451/2],...
                [0.1,0.1,0.1],...
                [0.1,0.1,0.082/2],...
                [0.1,0.1,0.1]};
            
            collision = 0;
       
            transforms = cell(1,7);
            midpoints = cell(1,7);
            NumberOfLinks = size(app.IRB1200.model.links);
            % disp(["Number of links on the robot: ", NumberOfLinks]);
            current_robq = app.IRB1200.model.getpos;
            
            for i = 1:NumberOfLinks(1,2)+1
                [X,Y,Z] = ellipsoid(0,0,0,IRB1200_link_sizes_for_collisions{i}(1),IRB1200_link_sizes_for_collisions{i}(2),IRB1200_link_sizes_for_collisions{i}(3));
                EllipsoidPoints = [X(:),Y(:),Z(:)];


                transforms{i} = app.fkineIRBlinks(current_robq,i);
                %EllipsoidCenterPoints{i} = transforms{i}(1:3,4);
                % Link 1 midpoint
                if i == 1
                    % The link is translated and then rotated so we need to
                    % determine the transform without the rotation
                    midpoints{i} = transforms{i}*trotx(pi/2);
                    base = app.IRB1200.model.base;
                    base = base.T;
                    midpoints{i}(1:3,4) = (transforms{i}(1:3,4) + base(1:3,4))/2;
                
                elseif i == 3
                    midpoints{i} = transforms{i}*trotx(pi/2);
                    midpoints{i}(1:3,4) = (transforms{i}(1:3,4) + transforms{i-1}(1:3,4))/2;
        
                % Link 4 midpoint
                elseif i == 4
                    midpoints{i} = transforms{i}*trotx(-pi/2);
                    midpoints{i}(1:3,4) = (transforms{i}(1:3,4) + transforms{i-1}(1:3,4))/2;
        
                % Link 5 midpoint
                elseif i == 5
                    midpoints{i} = transforms{i}*trotx(pi/2);
                    midpoints{i}(1:3,4) = (transforms{i}(1:3,4) + transforms{i-1}(1:3,4))/2;
                
                % All other link midpoints
                else
                    midpoints{i} = transforms{i};
                    midpoints{i}(1:3,4) = (transforms{i}(1:3,4) + transforms{i-1}(1:3,4))/2;
                end
        
                hold on
                EllipsoidPointsAndOnes = [midpoints{i} * [EllipsoidPoints,ones(size(EllipsoidPoints,1),1)]']';
                updatedEllipsoidPoints = EllipsoidPointsAndOnes(:,1:3);
                %plot3(updatedEllipsoidPoints(:,1), EllipsoidPointsAndOnes(:,2), EllipsoidPointsAndOnes(:,3));
            end

    verts = [get(app.table,'Vertices'), ones(size(get(app.table,'Vertices'),1), 1)];
    verts(:,4) = [];
    %plot3(verts(:,1),verts(:,2),verts(:,3));
%     plot3(0.3157, 0.3872, 1.0487);
%     disp(verts);

    %tic
    
    for i = 1:NumberOfLinks(1,2)+1
        % Old detection method
        % d = ((verts(:,1)-midpoints{i}(1,4))/self.ur5_link_sizes_for_collisions{i}(1)).^2 ...
        %   + ((verts(:,2)-midpoints{i}(2,4))/self.ur5_link_sizes_for_collisions{i}(2)).^2 ...
        %   + ((verts(:,3)-midpoints{i}(3,4))/self.ur5_link_sizes_for_collisions{i}(3)).^2;

        invT_ = inv(midpoints{i});

        for k = i:size(verts)
            vertex_transform = midpoints{i};
            vertex_transform(1:3,4) = [verts(k,1:3)]';  % Keep rotation from links but change the position to the vertex of ply file
            % vertex_transform = transl(verts(k,1),verts(k,2),verts(k,3));
            link_to_vertex = invT_*vertex_transform;

            % disp([verts(k,1),verts(k,2),verts(k,3)]);
            % disp([midpoints{i}(1,4),midpoints{i}(2,4),midpoints{i}(3,4)]);
            % disp(vertex_transform);

            if (abs(link_to_vertex(1,4)) <= abs(IRB1200_link_sizes_for_collisions{i}(1))) && ...
                (abs(link_to_vertex(2,4)) <= abs(IRB1200_link_sizes_for_collisions{i}(2))) && ...
                (abs(link_to_vertex(3,4)) <= abs(IRB1200_link_sizes_for_collisions{i}(3)))

                % disp([abs(link_to_vertex(1,4)), abs(self.ur5_link_sizes_for_collisions{i}(1))]);
                % disp([abs(link_to_vertex(2,4)), abs(self.ur5_link_sizes_for_collisions{i}(2))]);
                % disp([abs(link_to_vertex(3,4)), abs(self.ur5_link_sizes_for_collisions{i}(3))]);

                disp("intersection detected");
                collision = 1;
                
                disp(["Intersection link number: ", i]);
                % disp(k);
                disp("Collision detected at: ");
                disp(["x:", verts(k,1), "y: ", verts(k,2), "z: ", verts(k,3)]);

                [X,Y,Z] = ellipsoid(0,0,0,IRB1200_link_sizes_for_collisions{i}(1),IRB1200_link_sizes_for_collisions{i}(2),IRB1200_link_sizes_for_collisions{i}(3));
                EllipsoidPoints = [X(:),Y(:),Z(:)];
                EllipsoidPointsAndOnes = [midpoints{i} * [EllipsoidPoints,ones(size(EllipsoidPoints,1),1)]']';
                updatedEllipsoidPoints = EllipsoidPointsAndOnes(:,1:3);
                plot3(updatedEllipsoidPoints(:,1), EllipsoidPointsAndOnes(:,2), EllipsoidPointsAndOnes(:,3));

                % disp([midpoints{i}(1,4),midpoints{i}(2,4),midpoints{i}(3,4)]);
                % disp(vertex_transform);
                % disp(T);
                % disp(link_to_vertex);
                % disp([self.ur5_link_sizes_for_collisions{i}(1),self.ur5_link_sizes_for_collisions{i}(2),self.ur5_link_sizes_for_collisions{i}(3)]);

                break
                % ellipsoid(midpoints{i}(1,4),midpoints{i}(2,4),midpoints{i}(3,4),self.ur5_link_sizes_for_collisions{i}(1),self.ur5_link_sizes_for_collisions{i}(1),self.ur5_link_sizes_for_collisions{i}(1));
            end
        end


    end
    %disp(["Time to finish the for loop: " toc]);

    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        axis equal
        axis([-3 3 -3 3 0 2])
        hold on;
        
        % Set number of steps
        app.numSteps = 10;
        
        %% Initialising System
        disp('Initialising...');
        app.table=PlaceObject(['bar.ply'], [0,0,0]);
        app.table_vertices=get(app.table,'Vertices');
        tabletr=transl(0,0,0);
        transformedVertices=[app.table_vertices,ones(size(app.table_vertices,1),1)]*tabletr';
        set(app.table,'Vertices',transformedVertices(:,1:3));

%         
%         app.lightcurtain=PlaceObject(['lightcurtain.ply'], [-0.8,1.3,0]);
%         app.lightcurtain=get(app.lightcurtain,'Vertices');
%         %lightcurtaintr=transl(-0.8,1.3,0);
%         transformedVertices=[app.lightcurtain_vertices,ones(size(app.lightcurtain_vertices,1),1)]*lightcurtaintr';
%         set(app.lightcurtain,'Vertices',transformedVertices(:,1:3));
        
        %insert concrete floor
        surf([-3,-3;3,3],[-3,3;-3,3],[0.01,0.01;0.01,0.01],'CData',imread('concrete.jpg'),'FaceColor','texturemap');
        

        app.light = PlaceObject('lightcurtain.ply', [-0.8,1.3,0]);
        %insert safety structure
        PlaceObject('fireExtinguisherElevated.ply', [0 -1.5 0.5]);
        PlaceObject('emergencyStopButton.ply', [0.8 -1 0.5]);
        PlaceObject('barrier1.5x0.2x1m.ply',[0 -1.3 0]);
        PlaceObject('barrier1.5x0.2x1m.ply',[0 1.3 0]);
        
        % add alcohol 1 - 4
        app.gin=PlaceObject('greenbottle.ply', [0 0 0]);
        app.gin_vertices=get(app.gin,'Vertices');
        gintr=transl(-0.3,0.5,0.5);
        transformedVertices=[app.gin_vertices,ones(size(app.gin_vertices,1),1)]*gintr';
        set(app.gin,'Vertices',transformedVertices(:,1:3));
        
        app.vodka=PlaceObject('vodkabottle.ply', [0 0 0]);
        app.vodka_vertices=get(app.vodka,'Vertices');
        vodkatr=transl(-0.4,0.5,0.5);
        transformedVertices=[app.vodka_vertices,ones(size(app.vodka_vertices,1),1)]*vodkatr';
        set(app.vodka,'Vertices',transformedVertices(:,1:3));
        
        app.whiskey=PlaceObject('rumbottle.ply', [0 0 0]);
        app.whiskey_vertices=get(app.whiskey,'Vertices');
        whiskeytr=transl(-0.5,0.5,0.5);
        transformedVertices=[app.whiskey_vertices,ones(size(app.whiskey_vertices,1),1)]*whiskeytr';
        set(app.whiskey,'Vertices',transformedVertices(:,1:3));
        
        % add mixers 1 - 3
        app.coke=PlaceObject('rumbottle.ply', [0 0 0]);
        app.coke_vertices=get(app.coke,'Vertices');
        coketr=transl(-0.3,0.8,0.5);
        transformedVertices=[app.coke_vertices,ones(size(app.coke_vertices,1),1)]*coketr';
        set(app.coke,'Vertices',transformedVertices(:,1:3));
        
        app.lemonade=PlaceObject('greenbottle.ply', [0 0 0]);
        app.lemonade_vertices=get(app.lemonade,'Vertices');
        lemonadetr=transl(-0.4,0.8,0.5);
        transformedVertices=[app.lemonade_vertices,ones(size(app.lemonade_vertices,1),1)]*lemonadetr';
        set(app.lemonade,'Vertices',transformedVertices(:,1:3));
        
        app.orangeJuice=PlaceObject('orangebottle.ply', [0 0 0]);
        app.orangeJuice_vertices=get(app.orangeJuice,'Vertices');
        orangeJuicetr=transl(-0.5,0.8,0.5);
        transformedVertices=[app.orangeJuice_vertices,ones(size(app.orangeJuice_vertices,1),1)]*orangeJuicetr';
        set(app.orangeJuice,'Vertices',transformedVertices(:,1:3));
        
        % Create an ABB IRB 120 model
        app.IRB1200 = ABBIRB1200();
        app.IRB1200.model.base = app.IRB1200.model.base.T * transl(-0.4,0,0.5);
        app.IRB1200.model.animate(zeros(1, 6));
        drawnow;
        
        %Setup Gripper
        gripperOrigin = app.IRB1200.model.fkine(app.IRB1200.model.getpos());
        app.gripperL = Gripper(gripperOrigin.T * trotx(pi/2));
        app.gripperR = Gripper(gripperOrigin.T *trotx(-pi/2) * trotz(pi));
        
        %gripper movement q values for opening gripper and closing the gripper
        app.Open = [deg2rad(0) deg2rad(0) deg2rad(0)];
        app.Close = [deg2rad(25) deg2rad(0) deg2rad(-25)];
        %open gripper movement matrix
        app.qMatrixOpen = jtraj(app.Close,app.Open,app.numSteps);
        %close gripper movement matrix
        app.qMatrixClose = jtraj(app.Open,app.Close,app.numSteps);
        
        %IMPORTANT positions
        app.mixingpos = [0.2 -0.15 0.7];

        %Create DoBot Magician (mounted on Linear Rail) model
        app.DOBOT = LinearDobotMagician();

         
        end

        % Button pushed function: plotpersonButton
        function plotpersonButtonPushed(app, event)
                app.plotPos = [-1,-0.8,0];
                app.person = PlaceObject('cow.ply',app.plotPos);
          
        function lightcollision = lightCurtain(app,light)
                centerPoint = [-0.8,1.3,0] + [0,0,0.94];
                sizePerson = [0.65, 0.3, 1];
                 
                [X,Y,Z] = ellipsoid(centerPoint(1),centerPoint(2),centerPoint(3),sizePerson(1),sizePerson(2),sizePerson(3));
                EllipsoidPoints = [X(:),Y(:),Z(:)];
                plot3(EllipsoidPoints(:,1), EllipsoidPoints(:,2), EllipsoidPoints(:,3));
                 
                trPerson = transl(centerPoint);
                 
                verts = [get(light,'Vertices'), ones(size(get(light,'Vertices'),1),1)];
                verts(:,4) = [];
                 
                invTr = inv(trPerson);
                 
                for j = 1:size(verts)
                    vertTr = trPerson;
                    vertTr(1:3,4) = [verts(j,1:3)]';  % Keep rotation from links but change the position to the vertex of ply file
                    % vertex_transform = transl(verts(k,1),verts(k,2),verts(k,3));
                    distance = invTr * vertTr;
                 
                    if (abs(distance(1,4)) <= abs(sizePerson(1))) && ...
                            (abs(distance(2,4)) <= abs(sizePerson(2))) && ...
                            (abs(distance(3,4)) <= abs(sizePerson(3)))
                 
                        disp("intersection detected");
                        collision = 1;
                        disp("Collision detected at: ");
                        disp(["x:", verts(j,1), "y: ", verts(j,2), "z: ", verts(j,3)]);
                        % ellipsoid(midpoints{i}(1,4),midpoints{i}(2,4),midpoints{i}(3,4),self.ur5_link_sizes_for_collisions{i}(1),self.ur5_link_sizes_for_collisions{i}(1),self.ur5_link_sizes_for_collisions{i}(1));
                    end
                end
        end
        disp("intersection detected");
                uiwait
        end

        % Button pushed function: removepersonButton
        function removepersonButtonPushed(app, event)
            delete(app.person);
            
              function lightcollision = lightCurtain(app,light)
                centerPoint = [-0.8,1.3,0] + [0,0,0.94];
                sizePerson = [0.65, 0.3, 1];
                 
                [X,Y,Z] = ellipsoid(centerPoint(1),centerPoint(2),centerPoint(3),sizePerson(1),sizePerson(2),sizePerson(3));
                EllipsoidPoints = [X(:),Y(:),Z(:)];
                plot3(EllipsoidPoints(:,1), EllipsoidPoints(:,2), EllipsoidPoints(:,3));
                 
                trPerson = transl(centerPoint);
                 
                verts = [get(light,'Vertices'), ones(size(get(light,'Vertices'),1),1)];
                verts(:,4) = [];
                 
                invTr = inv(trPerson);
                 
                for j = 1:size(verts)
                    vertTr = trPerson;
                    vertTr(1:3,4) = [verts(j,1:3)]';  % Keep rotation from links but change the position to the vertex of ply file
                    % vertex_transform = transl(verts(k,1),verts(k,2),verts(k,3));
                    distance = invTr * vertTr;
                 
                    if (abs(distance(1,4)) <= abs(sizePerson(1))) && ...
                            (abs(distance(2,4)) <= abs(sizePerson(2))) && ...
                            (abs(distance(3,4)) <= abs(sizePerson(3)))
                 
                        disp("intersection detected");
                        collision = 1;
                        disp("Collision detected at: ");
                        disp(["x:", verts(j,1), "y: ", verts(j,2), "z: ", verts(j,3)]);
                        % ellipsoid(midpoints{i}(1,4),midpoints{i}(2,4),midpoints{i}(3,4),self.ur5_link_sizes_for_collisions{i}(1),self.ur5_link_sizes_for_collisions{i}(1),self.ur5_link_sizes_for_collisions{i}(1));
                    end
                end    
              end
              uiresume
        end

        % Button pushed function: VodkaLemonadeButton
        function VodkaLemonadeButtonPushed(app, event)
          app.makeVodkaLemonade(app.IRB1200, app.gripperR, app.gripperL, app.numSteps,app.mixingpos,app.qMatrixClose,app.qMatrixOpen,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice,app.DOBOT)
    
        end

        % Button pushed function: WhiskeyCokeButton
        function WhiskeyCokeButtonPushed(app, event)
            app.makeWhiskeyCoke(app.IRB1200, app.gripperR, app.gripperL, app.numSteps,app.mixingpos,app.qMatrixClose,app.qMatrixOpen,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice,app.DOBOT)
    
        end

        % Button pushed function: VodkaOrangeJuiceButton
        function VodkaOrangeJuiceButtonPushed(app, event)
            app.makeVodkaOJ(app.IRB1200, app.gripperR, app.gripperL, app.numSteps,app.mixingpos,app.qMatrixClose,app.qMatrixOpen,app.vodka_vertices,app.gin_vertices,app.whiskey_vertices,app.coke_vertices,app.lemonade_vertices,app.orangeJuice_vertices,app.vodka,app.gin,app.whiskey,app.coke,app.lemonade,app.orangeJuice,app.DOBOT)
    
        end

        % Value changed function: GripperSwitch
        function GripperSwitchValueChanged(app, event)
            value = app.GripperSwitch.Value;
            if strcmp(value,'Open')
                app.openGripper(app.gripperR,app.gripperL,app.qMatrixOpen)
            end
            if strcmp(value,'Close')
                app.closeGripper(app.gripperR,app.gripperL,app.qMatrixClose)
            end

        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            
            app.EStopStatus = true;
     
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
                       
            app.EStopStatus = false;
            

        end

        % Button pushed function: RESETROBOTButton
        function RESETROBOTButtonPushed(app, event)
            app.currentJoint = app.IRB1200.model.getpos();
            app.TargetJoint = [0 0 0 0 0 0];
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                pause(0.1);
                drawnow;
            end
            app.Joint1Slider.Value = 0;
            app.Joint2Slider.Value = 0;
            app.Joint3Slider.Value = 0;
            app.Joint4Slider.Value = 0;
            app.Joint5Slider.Value = 0;
            app.Joint6Slider.Value = 0;

            app.currentJoint = app.DOBOT.model.getpos();
            app.TargetJoint = [0 0 0 0 0 0];
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                drawnow;
            end
         
            app.Joint1Slider_2.Value = -0.01;
            app.Joint2Slider_2.Value = 0;
            app.Joint3Slider_2.Value = 0;
            app.Joint4Slider_2.Value = 0;
            app.Joint5Slider_2.Value = 0;
            app.Joint6Slider_2.Value = 0;
        end

        % Button pushed function: Seat1Button
        function Seat1ButtonPushed(app, event)
            app.s = 1;
        end

        % Button pushed function: Seat2Button
        function Seat2ButtonPushed(app, event)
            app.s = 2;
        end

        % Button pushed function: Seat3Button
        function Seat3ButtonPushed(app, event)
            app.s = 3;
        end

        % Button pushed function: MOVEButton
        function MOVEButtonPushed(app, event)
            

            if isempty(app.Xpos) 
                app.Xpos = 0;
            end
            if isempty(app.Ypos) 
                app.Ypos = 0;
            end
            if isempty(app.Zpos) 
                app.Zpos = 0;
            end
            app.currentJoint = app.IRB1200.model.getpos();


            app.TargetJoint = app.IRB1200.model.ikcon(transl(app.Xpos,app.Ypos,0.1+app.Zpos)*trotx(180,"deg"));
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end  


        end

        % Button pushed function: MOVEButton_2
        function MOVEButton_2Pushed(app, event)
            
            if isempty(app.Xpos2) 
                app.Xpos2 = 0;
            end
            if isempty(app.Ypos2) 
                app.Ypos2 = 0;
            end
            if isempty(app.Zpos2) 
                app.Zpos2 = 0;
            end
            app.currentJoint = app.DOBOT.model.getpos();
            

            app.TargetJoint = app.DOBOT.model.ikcon(transl(app.Xpos2,app.Ypos2,0.1+app.Zpos2)*trotx(180,"deg"));
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                if app.EStopStatus == true
                        return
                end
                drawnow;
            end  
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.Xpos = value;
        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
            value = app.XEditField_2.Value;
            app.Xpos2 = value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.Ypos = value;
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            value = app.YEditField_2.Value;
            app.Ypos2 = value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.Zpos = value;
        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)
            value = app.ZEditField_2.Value;
            app.Zpos2 = value;
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            %app.Joint1Slider.Value = value; % Update the Slider value
            app.currentJoint = app.IRB1200.model.getpos();
            app.TargetJoint = [deg2rad(value) app.currentJoint(2:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                pause(0.1);
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
            
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.currentJoint = app.IRB1200.model.getpos();
            app.TargetJoint = [app.currentJoint(1) deg2rad(value) app.currentJoint(3:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                pause(0.1);
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.currentJoint = app.IRB1200.model.getpos();
            app.TargetJoint = [app.currentJoint(1:2) deg2rad(value) app.currentJoint(4:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                pause(0.1);
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
            
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            app.currentJoint = app.IRB1200.model.getpos();
            app.TargetJoint = [app.currentJoint(1:3) deg2rad(value) app.currentJoint(5:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                pause(0.1);
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            app.currentJoint = app.IRB1200.model.getpos();
            app.TargetJoint = [app.currentJoint(1:4) deg2rad(value) app.currentJoint(6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                pause(0.1);
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            app.currentJoint = app.IRB1200.model.getpos();
            app.TargetJoint = [app.currentJoint(1:5) deg2rad(value) ];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.IRB1200.model.animate(qMatrix(i,:));
                app.gripperR.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(-pi/2) * trotz(pi); 
                app.gripperL.model.base = app.IRB1200.model.fkine(app.IRB1200.model.getpos).T * trotx(pi/2);
                %animate the change of position for gripper
                app.gripperR.model.animate(app.gripperR.model.getpos);
                app.gripperL.model.animate(app.gripperL.model.getpos);
                pause(0.1);
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint1Slider_2
        function Joint1Slider_2ValueChanged(app, event)
            value = app.Joint1Slider_2.Value;
            app.currentJoint = app.DOBOT.model.getpos();
            app.TargetJoint = [deg2rad(value) app.currentJoint(2:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint2Slider_2
        function Joint2Slider_2ValueChanged(app, event)
            value = app.Joint2Slider_2.Value;
            app.currentJoint = app.DOBOT.model.getpos();
            app.TargetJoint = [app.currentJoint(1) deg2rad(value) app.currentJoint(3:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint3Slider_2
        function Joint3Slider_2ValueChanged(app, event)
            value = app.Joint3Slider_2.Value;
            app.currentJoint = app.DOBOT.model.getpos();
            app.TargetJoint = [app.currentJoint(1:2) deg2rad(value) app.currentJoint(4:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint4Slider_2
        function Joint4Slider_2ValueChanged(app, event)
            value = app.Joint4Slider_2.Value;
            app.currentJoint = app.DOBOT.model.getpos();
            app.TargetJoint = [app.currentJoint(1:3) deg2rad(value) app.currentJoint(5:6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint5Slider_2
        function Joint5Slider_2ValueChanged(app, event)
            value = app.Joint5Slider_2.Value;
            app.currentJoint = app.DOBOT.model.getpos();
            app.TargetJoint = [app.currentJoint(1:4) deg2rad(value) app.currentJoint(6)];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end

        % Value changed function: Joint6Slider_2
        function Joint6Slider_2ValueChanged(app, event)
            value = app.Joint6Slider_2.Value;
            app.currentJoint = app.DOBOT.model.getpos();
            app.TargetJoint = [app.currentJoint(1:5) deg2rad(value) ];
 
            qMatrix = jtraj(app.currentJoint, app.TargetJoint, app.steps);
 
            for i = 1:app.steps
                app.DOBOT.model.animate(qMatrix(i,:));
                if app.EStopStatus == true
                        return
                end
                drawnow;
                collision = app.checkCollisions();
                if collision == 1
                        return
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ROBOT')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VodkaLemonadeButton = uibutton(app.UIFigure, 'push');
            app.VodkaLemonadeButton.ButtonPushedFcn = createCallbackFcn(app, @VodkaLemonadeButtonPushed, true);
            app.VodkaLemonadeButton.Text = 'VodkaLemonade';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-165 165];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-110 110];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-110 70];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-160 160];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-120 120];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-400 400];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.WhiskeyCokeButton = uibutton(app.UIFigure, 'push');
            app.WhiskeyCokeButton.ButtonPushedFcn = createCallbackFcn(app, @WhiskeyCokeButtonPushed, true);
            app.WhiskeyCokeButton.Text = 'WhiskeyCoke';
            app.VodkaOrangeJuiceButton = uibutton(app.UIFigure, 'push');
            app.VodkaOrangeJuiceButton.ButtonPushedFcn = createCallbackFcn(app, @VodkaOrangeJuiceButtonPushed, true);
            app.VodkaOrangeJuiceButton.Text = 'VodkaOrangeJuice';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E STOP';
            app.RESETROBOTButton = uibutton(app.UIFigure, 'push');
            app.RESETROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @RESETROBOTButtonPushed, true);
            app.RESETROBOTButton.Text = {'RESET '; 'ROBOT'};
            app.GripperSwitchLabel = uilabel(app.UIFigure);
            app.GripperSwitchLabel.Text = 'Gripper';
            app.GripperSwitch = uiswitch(app.UIFigure, 'slider');
            app.GripperSwitch.Items = {'Open', 'Close'};
            app.GripperSwitch.ValueChangedFcn = createCallbackFcn(app, @GripperSwitchValueChanged, true);
            app.GripperSwitch.Value = 'Open';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.MOVEButton = uibutton(app.UIFigure, 'push');
            app.MOVEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEButtonPushed, true);
            app.MOVEButton.Text = 'MOVE';
            app.Seat1Button = uibutton(app.UIFigure, 'push');
            app.Seat1Button.ButtonPushedFcn = createCallbackFcn(app, @Seat1ButtonPushed, true);
            app.Seat1Button.Text = 'Seat 1';
            app.Seat2Button = uibutton(app.UIFigure, 'push');
            app.Seat2Button.ButtonPushedFcn = createCallbackFcn(app, @Seat2ButtonPushed, true);
            app.Seat2Button.Text = 'Seat 2';
            app.Seat3Button = uibutton(app.UIFigure, 'push');
            app.Seat3Button.ButtonPushedFcn = createCallbackFcn(app, @Seat3ButtonPushed, true);
            app.Seat3Button.Text = 'Seat 3';
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint1';
            app.Joint1Slider_2 = uislider(app.UIFigure);
            app.Joint1Slider_2.Limits = [-46 -0.01];
            app.Joint1Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_2ValueChanged, true);
            app.Joint1Slider_2.Value = -0.01;
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint2';
            app.Joint2Slider_2 = uislider(app.UIFigure);
            app.Joint2Slider_2.Limits = [-135 135];
            app.Joint2Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_2ValueChanged, true);
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint3';
            app.Joint3Slider_2 = uislider(app.UIFigure);
            app.Joint3Slider_2.Limits = [-80 80];
            app.Joint3Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_2ValueChanged, true);
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint4';
            app.Joint4Slider_2 = uislider(app.UIFigure);
            app.Joint4Slider_2.Limits = [-5 85];
            app.Joint4Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_2ValueChanged, true);
            app.Joint5Slider_2Label = uilabel(app.UIFigure);
            app.Joint5Slider_2Label.Text = 'Joint5';
            app.Joint5Slider_2 = uislider(app.UIFigure);
            app.Joint5Slider_2.Limits = [-180 180];
            app.Joint5Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_2ValueChanged, true);
            app.Joint6Slider_2Label = uilabel(app.UIFigure);
            app.Joint6Slider_2Label.Text = 'Joint6';
            app.Joint6Slider_2 = uislider(app.UIFigure);
            app.Joint6Slider_2.Limits = [-85 85];
            app.Joint6Slider_2.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_2ValueChanged, true);
            app.MOVEButton_2 = uibutton(app.UIFigure, 'push');
            app.MOVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @MOVEButton_2Pushed, true);
            app.MOVEButton_2.Text = 'MOVE';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.ABBIRB1200Label = uilabel(app.UIFigure);
            app.ABBIRB1200Label.Text = 'ABB IRB1200';
            app.LinearDobotMagicianLabel = uilabel(app.UIFigure);
            app.LinearDobotMagicianLabel.Text = 'Linear Dobot Magician';
            app.plotpersonButton = uibutton(app.UIFigure, 'push');
            app.plotpersonButton.ButtonPushedFcn = createCallbackFcn(app, @plotpersonButtonPushed, true);
            app.plotpersonButton.Text = 'plot person';
            app.removepersonButton = uibutton(app.UIFigure, 'push');
            app.removepersonButton.ButtonPushedFcn = createCallbackFcn(app, @removepersonButtonPushed, true);
            app.removepersonButton.Text = 'remove person';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end